classdef Interfaz_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Cargar               matlab.ui.control.Button
        EditField            matlab.ui.control.EditField
        AudioMixerLabel      matlab.ui.control.Label
        Play                 matlab.ui.control.Button
        Pause                matlab.ui.control.Button
        Resume               matlab.ui.control.Button
        Stop                 matlab.ui.control.Button
        FiltroDropDownLabel  matlab.ui.control.Label
        FiltroDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        archivo     % Description
        player
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Cargar
        function CargarButtonPushed(app, event)
            [file,path] = uigetfile({'*.wav;*.mp3', 'Audio Files'});
            if isequal(file,0)
                app.EditField.BackgroundColor = 'red';
            else
                app.EditField.BackgroundColor = 'white';
                app.EditField.Value = file;
                app.archivo = file;
                [audio,Fs] = audioread(app.archivo);
                app.player = audioplayer(audio,Fs);
            end
        end

        % Button pushed function: Play
        function PlayButtonPushed(app, event)
            play(app.player);
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            pause(app.player);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            stop(app.player);
        end

        % Button pushed function: Resume
        function ResumeButtonPushed(app, event)
            resume(app.player);
        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
            value = app.FiltroDropDown.Value;
            %switch val
            %    case {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'}
                    
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Cargar = uibutton(app.UIFigure, 'push');
            app.Cargar.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.Cargar.Icon = 'upload.png';
            app.Cargar.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Editable = 'off';
            app.AudioMixerLabel = uilabel(app.UIFigure);
            app.AudioMixerLabel.Text = 'Audio Mixer';
            app.Play = uibutton(app.UIFigure, 'push');
            app.Play.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.Play.Icon = 'play.png';
            app.Play.Text = '';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Icon = 'pause.png';
            app.Pause.Text = '';
            app.Resume = uibutton(app.UIFigure, 'push');
            app.Resume.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.Resume.Icon = 'resume.png';
            app.Resume.Text = '';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Icon = 'stop.png';
            app.Stop.Text = '';
            app.FiltroDropDownLabel = uilabel(app.UIFigure);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.UIFigure);
            app.FiltroDropDown.Items = {'Pasa-altas', 'Pasabajas', 'Pasabandas', 'Notch'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'Pasa-altas';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end